classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DisplayHistogramTab             matlab.ui.container.Tab
        DisplayHistogramLabel           matlab.ui.control.Label
        AddImageDisplayHistogramButton  matlab.ui.control.Button
        DisplayHistogramAxes            matlab.ui.control.UIAxes
        DisplayHistogramCitraInputAxes  matlab.ui.control.UIAxes
        ImageEnhancementTab             matlab.ui.container.Tab
        AddImageIEButton                matlab.ui.control.Button
        TransformasiPangkatPanel        matlab.ui.container.Panel
        gammaLabel                      matlab.ui.control.Label
        cpowLabel                       matlab.ui.control.Label
        scrgammaLabel                   matlab.ui.control.Label
        gammaSlider                     matlab.ui.control.Slider
        gammaSliderLabel                matlab.ui.control.Label
        cpowSlider                      matlab.ui.control.Slider
        cSliderLabel_2                  matlab.ui.control.Label
        TransformasiLogPanel            matlab.ui.container.Panel
        clogLabel                       matlab.ui.control.Label
        sclog1rLabel                    matlab.ui.control.Label
        clogSlider                      matlab.ui.control.Slider
        cSliderLabel                    matlab.ui.control.Label
        MetodeDropDown                  matlab.ui.control.DropDown
        MetodeDropDownLabel             matlab.ui.control.Label
        ImageEnhancementLabel           matlab.ui.control.Label
        ImageBrighteningPanel           matlab.ui.container.Panel
        bLabel                          matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        sarbLabel                       matlab.ui.control.Label
        bSlider                         matlab.ui.control.Slider
        bSliderLabel                    matlab.ui.control.Label
        aSlider                         matlab.ui.control.Slider
        aSliderLabel                    matlab.ui.control.Label
        HistOutputIEAxes                matlab.ui.control.UIAxes
        IECitraOutputAxes               matlab.ui.control.UIAxes
        HistInputIEAxes                 matlab.ui.control.UIAxes
        IECitraInputAxes                matlab.ui.control.UIAxes
        HistogramEqualizationTab        matlab.ui.container.Tab
        HistogramEqualizationLabel      matlab.ui.control.Label
        AddImageDisplayHEButton         matlab.ui.control.Button
        HistOutputHEAxes                matlab.ui.control.UIAxes
        HECitraOutputAxes               matlab.ui.control.UIAxes
        HistInputHEAxes                 matlab.ui.control.UIAxes
        HECitraInputAxes                matlab.ui.control.UIAxes
        HistogramSpecificationTab       matlab.ui.container.Tab
        AddImageRefHSButton             matlab.ui.control.Button
        AddImageInputHSButton           matlab.ui.control.Button
        HistogramSpecificationLabel     matlab.ui.control.Label
        HistOutputHSAxes                matlab.ui.control.UIAxes
        HSCitraOutputAxes               matlab.ui.control.UIAxes
        HistRefHSAxes                   matlab.ui.control.UIAxes
        HistInputHSAxes                 matlab.ui.control.UIAxes
        HSCitraRefAxes                  matlab.ui.control.UIAxes
        HSCitraInputAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img_ie = []; % Initial definition for image of image enhancement tab
        img_hs_in = []; % Initial definition for image input of histogram specification tab
        img_hs_ref = []; % Initial definition for image ref of histogram specification tab
    end
    
    methods (Access = public)
        
        function freq = getFreq(~, Img)
        N = 256;
        freq = zeros(1, N);
        for idx = 1:numel(Img)
            freq(Img(idx) + 1) = freq(Img(idx) + 1) + 1;
        end
        end
        
        function Img = image_brightening(~, Img, a, b)
        % s = ar + b
        N = 256;
        s = zeros(1, N);
        for i = 1:N
            s_temp = a * (i - 1) + b;
            if s_temp > N - 1
                s(i) = N - 1;
            elseif s_temp < 0
                s(i) = 0;
            else
                s(i) = s_temp;
            end
        end
        for idx = 1:numel(Img)
            Img(idx) = s(Img(idx) + 1);
        end
        end
        
        function Img = log_transformation(~, Img, c)
        % s = c * log (1 + r)
        N = 256;
        s = zeros(1, N);
        for i = 1:N
            % Karena indexnya dimulai dari 1 jadi i nya perlu dikurang 1
            % s = c * log10(1 + (i -1)) = c * log10(i)
            s_temp = c * log10(i);
            if s_temp > N - 1
                s(i) = N - 1;
            elseif s_temp < 0
                s(i) = 0;
            else
                s(i) = s_temp;
            end
        end
        for idx = 1:numel(Img)
            Img(idx) = s(Img(idx) + 1);
        end
        end
        
        function Img = power_transformation(~, Img, c, gamma)
        % s = c * r ^ gamma
        N = 256;
        s = zeros(1, N);
        for i = 1:N
            s_temp = c * (i - 1) ^ gamma;
            if s_temp > N - 1
                s(i) = N - 1;
            elseif s_temp < 0
                s(i) = 0;
            else
                s(i) = s_temp;
            end
        end
        for idx = 1:numel(Img)
            Img(idx) = s(Img(idx) + 1);
        end
        end
        
        function Img = contrast_streching(~, Img)
        % s = (r - rmin) * ((N - 1) / (rmax + rmin))
        N = 256;
        rmax = max(Img, [], 'all');
        rmin = min(Img, [], 'all');
        s = zeros(1, N);
        for i = 1:N
            s_temp = (i - 1 - rmin) * ((N - 1)/ (rmax - rmin));
            if s_temp > N - 1
                s(i) = N - 1;
            elseif s_temp < 0
                s(i) = 0;
            else
                s(i) = s_temp;
            end
        end
        for idx = 1:numel(Img)
            Img(idx) = s(Img(idx) + 1);
        end
        end
        
        function s = histogram_equalization_transformer(app, Img)
        N = 256;
        freq = getFreq(app, Img);
        n = numel(Img);
        norm_freq = freq / n;
        s = zeros(1, N);
        s(1) = norm_freq(1);
        for i = 2:N 
            s(i) = norm_freq(i) + s(i-1);
        end
        s = s * (N - 1);
        end
        
        function Img = histogram_equalization(app, Img)
        s = histogram_equalization_transformer(app, Img);
        for idx = 1:numel(Img)
            Img(idx) = s(Img(idx) + 1);
        end
        end
        
        function Img = histogram_specification(app, Img, Img_referensi)
        s = histogram_equalization_transformer(app, Img);
        z = histogram_equalization_transformer(app, Img_referensi);
        N = 256;
        for i = 1:N
            [~, imin] = min(abs(z - s(i)));
            s(i) = imin - 1;
        end
        for idx = 1:numel(Img)
            Img(idx) = s(Img(idx) + 1);
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddImageDisplayHistogramButton
        function AddImageDisplayHistogramButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            citra_input = imread(file);
            imfreq = getFreq(app, citra_input);
            imshow(citra_input, 'Parent', app.DisplayHistogramCitraInputAxes);
            bar(imfreq, 'Parent', app.DisplayHistogramAxes);
        end

        % Button pushed function: AddImageDisplayHEButton
        function AddImageDisplayHEButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            citra_input = imread(file);
            citra_output = histogram_equalization(app, citra_input);
            imfreq = getFreq(app, citra_input);
            outfreq = getFreq(app, citra_output);
            imshow(citra_input, 'Parent', app.HECitraInputAxes);
            imshow(citra_output, 'Parent', app.HECitraOutputAxes);
            bar(imfreq, 'Parent', app.HistInputHEAxes);
            bar(outfreq, 'Parent', app.HistOutputHEAxes);
        end

        % Value changed function: MetodeDropDown
        function MetodeDropDownValueChanged(app, event)
            value = app.MetodeDropDown.Value;
            if strcmp(value, 'Image Brightening')
                set(app.ImageBrighteningPanel, 'Visible', 'on');
                set(app.TransformasiLogPanel, 'Visible', 'off');
                set(app.TransformasiPangkatPanel, 'Visible', 'off');
            elseif strcmp(value, 'Transformasi Log')
                set(app.ImageBrighteningPanel, 'Visible', 'off');
                set(app.TransformasiLogPanel, 'Visible', 'on');
                set(app.TransformasiPangkatPanel, 'Visible', 'off');
            elseif strcmp(value, 'Transformasi Pangkat')
                set(app.ImageBrighteningPanel, 'Visible', 'off');
                set(app.TransformasiLogPanel, 'Visible', 'off');
                set(app.TransformasiPangkatPanel, 'Visible', 'on');
            else
                set(app.ImageBrighteningPanel, 'Visible', 'off');
                set(app.TransformasiLogPanel, 'Visible', 'off');
                set(app.TransformasiPangkatPanel, 'Visible', 'off');
            end

            if isempty(app.img_ie) == 0
                if strcmp(value, 'Image Brightening')
                    citra_output = image_brightening(app, app.img_ie, app.aSlider.Value, app.bSlider.Value);
                    imfreq = getFreq(app, app.img_ie);
                    outfreq = getFreq(app, citra_output);
                    imshow(app.img_ie, 'Parent', app.IECitraInputAxes);
                    imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                    bar(imfreq, 'Parent', app.HistInputIEAxes);
                    bar(outfreq, 'Parent', app.HistOutputIEAxes);
                elseif strcmp(value, 'Transformasi Log')
                    citra_output = log_transformation(app, app.img_ie, app.clogSlider.Value);
                    imfreq = getFreq(app, app.img_ie);
                    outfreq = getFreq(app, citra_output);
                    imshow(app.img_ie, 'Parent', app.IECitraInputAxes);
                    imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                    bar(imfreq, 'Parent', app.HistInputIEAxes);
                    bar(outfreq, 'Parent', app.HistOutputIEAxes);
                elseif strcmp(value, 'Transformasi Pangkat')
                    citra_output = power_transformation(app, app.img_ie, app.cpowSlider.Value, app.gammaSlider.Value);
                    imfreq = getFreq(app, app.img_ie);
                    outfreq = getFreq(app, citra_output);
                    imshow(app.img_ie, 'Parent', app.IECitraInputAxes);
                    imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                    bar(imfreq, 'Parent', app.HistInputIEAxes);
                    bar(outfreq, 'Parent', app.HistOutputIEAxes);
                elseif strcmp(value, 'Peregangan Kontras')
                    citra_output = contrast_streching(app, app.img_ie);
                    imfreq = getFreq(app, app.img_ie);
                    outfreq = getFreq(app, citra_output);
                    imshow(app.img_ie, 'Parent', app.IECitraInputAxes);
                    imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                    bar(imfreq, 'Parent', app.HistInputIEAxes);
                    bar(outfreq, 'Parent', app.HistOutputIEAxes);
                end
            end
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            changingValue = event.Value;
            app.aLabel.Text = string(round(changingValue, 2));
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            changingValue = event.Value;
            app.bLabel.Text = string(round(changingValue, 2));
        end

        % Value changing function: cpowSlider
        function cpowSliderValueChanging(app, event)
            changingValue = event.Value;
            app.cpowLabel.Text = string(round(changingValue, 2));
        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.gammaLabel.Text = string(round(changingValue, 2));
        end

        % Value changing function: clogSlider
        function clogSliderValueChanging(app, event)
            changingValue = event.Value;
            app.clogLabel.Text = string(round(changingValue, 2));
        end

        % Button pushed function: AddImageIEButton
        function AddImageIEButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            app.img_ie = imread(file);
            value = app.MetodeDropDown.Value;
            if isempty(app.img_ie) == 0
                if strcmp(value, 'Image Brightening')
                    citra_output = image_brightening(app, app.img_ie, app.aSlider.Value, app.bSlider.Value);
                    imfreq = getFreq(app, app.img_ie);
                    outfreq = getFreq(app, citra_output);
                    imshow(app.img_ie, 'Parent', app.IECitraInputAxes);
                    imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                    bar(imfreq, 'Parent', app.HistInputIEAxes);
                    bar(outfreq, 'Parent', app.HistOutputIEAxes);
                elseif strcmp(value, 'Transformasi Log')
                    citra_output = log_transformation(app, app.img_ie, app.clogSlider.Value);
                    imfreq = getFreq(app, app.img_ie);
                    outfreq = getFreq(app, citra_output);
                    imshow(app.img_ie, 'Parent', app.IECitraInputAxes);
                    imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                    bar(imfreq, 'Parent', app.HistInputIEAxes);
                    bar(outfreq, 'Parent', app.HistOutputIEAxes);
                elseif strcmp(value, 'Transformasi Pangkat')
                    citra_output = power_transformation(app, app.img_ie, app.cpowSlider.Value, app.gammaSlider.Value);
                    imfreq = getFreq(app, app.img_ie);
                    outfreq = getFreq(app, citra_output);
                    imshow(app.img_ie, 'Parent', app.IECitraInputAxes);
                    imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                    bar(imfreq, 'Parent', app.HistInputIEAxes);
                    bar(outfreq, 'Parent', app.HistOutputIEAxes);
                elseif strcmp(value, 'Peregangan Kontras')
                    citra_output = contrast_streching(app, app.img_ie);
                    imfreq = getFreq(app, app.img_ie);
                    outfreq = getFreq(app, citra_output);
                    imshow(app.img_ie, 'Parent', app.IECitraInputAxes);
                    imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                    bar(imfreq, 'Parent', app.HistInputIEAxes);
                    bar(outfreq, 'Parent', app.HistOutputIEAxes);
                end
            end
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            % value = app.aSlider.Value;
            if isempty(app.img_ie) == 0
                citra_output = image_brightening(app, app.img_ie, app.aSlider.Value, app.bSlider.Value);
                outfreq = getFreq(app, citra_output);
                imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                bar(outfreq, 'Parent', app.HistOutputIEAxes);
            end
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            % value = app.bSlider.Value;
            if isempty(app.img_ie) == 0
                citra_output = image_brightening(app, app.img_ie, app.aSlider.Value, app.bSlider.Value);
                outfreq = getFreq(app, citra_output);
                imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                bar(outfreq, 'Parent', app.HistOutputIEAxes);
            end
        end

        % Value changed function: clogSlider
        function clogSliderValueChanged(app, event)
            % value = app.clogSlider.Value;
            if isempty(app.img_ie) == 0
                citra_output = log_transformation(app, app.img_ie, app.clogSlider.Value);
                outfreq = getFreq(app, citra_output);
                imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                bar(outfreq, 'Parent', app.HistOutputIEAxes);
            end
        end

        % Value changed function: cpowSlider
        function cpowSliderValueChanged(app, event)
            % value = app.cpowSlider.Value;
            if isempty(app.img_ie) == 0
                citra_output = power_transformation(app, app.img_ie, app.cpowSlider.Value, app.gammaSlider.Value);
                outfreq = getFreq(app, citra_output);
                imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                bar(outfreq, 'Parent', app.HistOutputIEAxes);
            end
        end

        % Value changed function: gammaSlider
        function gammaSliderValueChanged(app, event)
            % value = app.gammaSlider.Value;
            if isempty(app.img_ie) == 0
                citra_output = power_transformation(app, app.img_ie, app.cpowSlider.Value, app.gammaSlider.Value);
                outfreq = getFreq(app, citra_output);
                imshow(citra_output, 'Parent', app.IECitraOutputAxes);
                bar(outfreq, 'Parent', app.HistOutputIEAxes);
            end
        end

        % Button pushed function: AddImageInputHSButton
        function AddImageInputHSButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Input');
            file = [path, file];
            app.img_hs_in = imread(file);
            imshow(app.img_hs_in, 'Parent', app.HSCitraInputAxes);
            imfreq = getFreq(app, app.img_hs_in);
            bar(imfreq, 'Parent', app.HistInputHSAxes);
            if and(isempty(app.img_hs_in) == 0, isempty(app.img_hs_ref) == 0)
                citra_output = histogram_specification(app, app.img_hs_in, app.img_hs_ref);
                outfreq = getFreq(app, citra_output);
                imshow(citra_output, 'Parent', app.HSCitraOutputAxes);
                bar(outfreq, 'Parent', app.HistOutputHSAxes);
            end
        end

        % Button pushed function: AddImageRefHSButton
        function AddImageRefHSButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.oct;*.tif;*.jpeg'}, 'Select Citra Referensi');
            file = [path, file];
            app.img_hs_ref = imread(file);
            imshow(app.img_hs_ref, 'Parent', app.HSCitraRefAxes);
            imfreq = getFreq(app, app.img_hs_ref);
            bar(imfreq, 'Parent', app.HistRefHSAxes);
            if and(isempty(app.img_hs_in) == 0, isempty(app.img_hs_ref) == 0)
                citra_output = histogram_specification(app, app.img_hs_in, app.img_hs_ref);
                outfreq = getFreq(app, citra_output);
                imshow(citra_output, 'Parent', app.HSCitraOutputAxes);
                bar(outfreq, 'Parent', app.HistOutputHSAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DisplayHistogramTab = uitab(app.TabGroup);
            app.DisplayHistogramTab.Title = 'Display Histogram';
            app.DisplayHistogramCitraInputAxes = uiaxes(app.DisplayHistogramTab);
            title(app.DisplayHistogramCitraInputAxes, 'Citra Input')
            app.DisplayHistogramCitraInputAxes.XTick = [];
            app.DisplayHistogramCitraInputAxes.YTick = [];
            app.DisplayHistogramCitraInputAxes.ZTick = [];
            app.DisplayHistogramAxes = uiaxes(app.DisplayHistogramTab);
            title(app.DisplayHistogramAxes, 'Histogram CItra')
            app.DisplayHistogramAxes.XTick = [];
            app.DisplayHistogramAxes.YTick = [];
            app.DisplayHistogramAxes.ZTick = [];
            app.AddImageDisplayHistogramButton = uibutton(app.DisplayHistogramTab, 'push');
            app.AddImageDisplayHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageDisplayHistogramButtonPushed, true);
            app.AddImageDisplayHistogramButton.Text = 'Add Image';
            app.DisplayHistogramLabel = uilabel(app.DisplayHistogramTab);
            app.DisplayHistogramLabel.Text = 'Display Histogram';
            app.ImageEnhancementTab = uitab(app.TabGroup);
            app.ImageEnhancementTab.Title = 'Image Enhancement';
            app.IECitraInputAxes = uiaxes(app.ImageEnhancementTab);
            title(app.IECitraInputAxes, 'Citra Input')
            app.IECitraInputAxes.XTick = [];
            app.IECitraInputAxes.YTick = [];
            app.IECitraInputAxes.ZTick = [];
            app.HistInputIEAxes = uiaxes(app.ImageEnhancementTab);
            title(app.HistInputIEAxes, 'Histogram Citra')
            app.HistInputIEAxes.XTick = [];
            app.HistInputIEAxes.YTick = [];
            app.HistInputIEAxes.ZTick = [];
            app.IECitraOutputAxes = uiaxes(app.ImageEnhancementTab);
            title(app.IECitraOutputAxes, 'Citra Hasil Perbaikan')
            app.IECitraOutputAxes.XTick = [];
            app.IECitraOutputAxes.YTick = [];
            app.IECitraOutputAxes.ZTick = [];
            app.HistOutputIEAxes = uiaxes(app.ImageEnhancementTab);
            title(app.HistOutputIEAxes, 'Histogram Citra Hasil Perbaikan')
            app.HistOutputIEAxes.XTick = [];
            app.HistOutputIEAxes.YTick = [];
            app.HistOutputIEAxes.ZTick = [];
            app.ImageBrighteningPanel = uipanel(app.ImageEnhancementTab);
            app.ImageBrighteningPanel.Title = 'Image Brightening';
            app.aSliderLabel = uilabel(app.ImageBrighteningPanel);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.ImageBrighteningPanel);
            app.aSlider.Limits = [-25 25];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.Value = 1;
            app.bSliderLabel = uilabel(app.ImageBrighteningPanel);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.ImageBrighteningPanel);
            app.bSlider.Limits = [-255 255];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.sarbLabel = uilabel(app.ImageBrighteningPanel);
            app.sarbLabel.Text = 's = a * r + b';
            app.aLabel = uilabel(app.ImageBrighteningPanel);
            app.aLabel.Text = '1';
            app.bLabel = uilabel(app.ImageBrighteningPanel);
            app.bLabel.Text = '0';
            app.ImageEnhancementLabel = uilabel(app.ImageEnhancementTab);
            app.ImageEnhancementLabel.Text = 'Image Enhancement';
            app.MetodeDropDownLabel = uilabel(app.ImageEnhancementTab);
            app.MetodeDropDownLabel.Text = 'Metode';
            app.MetodeDropDown = uidropdown(app.ImageEnhancementTab);
            app.MetodeDropDown.Items = {'Image Brightening', 'Transformasi Log', 'Transformasi Pangkat', 'Peregangan Kontras'};
            app.MetodeDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodeDropDownValueChanged, true);
            app.MetodeDropDown.Value = 'Image Brightening';
            app.TransformasiLogPanel = uipanel(app.ImageEnhancementTab);
            app.TransformasiLogPanel.Title = 'Transformasi Log';
            app.TransformasiLogPanel.Visible = 'off';
            app.cSliderLabel = uilabel(app.TransformasiLogPanel);
            app.cSliderLabel.Text = 'c';
            app.clogSlider = uislider(app.TransformasiLogPanel);
            app.clogSlider.Limits = [0 106];
            app.clogSlider.ValueChangedFcn = createCallbackFcn(app, @clogSliderValueChanged, true);
            app.clogSlider.ValueChangingFcn = createCallbackFcn(app, @clogSliderValueChanging, true);
            app.clogSlider.Value = 50;
            app.sclog1rLabel = uilabel(app.TransformasiLogPanel);
            app.sclog1rLabel.Text = 's = c * log(1 + r)';
            app.clogLabel = uilabel(app.TransformasiLogPanel);
            app.clogLabel.Text = '50';
            app.TransformasiPangkatPanel = uipanel(app.ImageEnhancementTab);
            app.TransformasiPangkatPanel.Title = 'Transformasi Pangkat';
            app.TransformasiPangkatPanel.Visible = 'off';
            app.cSliderLabel_2 = uilabel(app.TransformasiPangkatPanel);
            app.cSliderLabel_2.Text = 'c';
            app.cpowSlider = uislider(app.TransformasiPangkatPanel);
            app.cpowSlider.Limits = [0 25];
            app.cpowSlider.ValueChangedFcn = createCallbackFcn(app, @cpowSliderValueChanged, true);
            app.cpowSlider.ValueChangingFcn = createCallbackFcn(app, @cpowSliderValueChanging, true);
            app.cpowSlider.Value = 1;
            app.gammaSliderLabel = uilabel(app.TransformasiPangkatPanel);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.TransformasiPangkatPanel);
            app.gammaSlider.Limits = [0 10];
            app.gammaSlider.ValueChangedFcn = createCallbackFcn(app, @gammaSliderValueChanged, true);
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.gammaSlider.Value = 1;
            app.scrgammaLabel = uilabel(app.TransformasiPangkatPanel);
            app.scrgammaLabel.Text = 's = c * r ^ gamma';
            app.cpowLabel = uilabel(app.TransformasiPangkatPanel);
            app.cpowLabel.Text = '1';
            app.gammaLabel = uilabel(app.TransformasiPangkatPanel);
            app.gammaLabel.Text = '1';
            app.AddImageIEButton = uibutton(app.ImageEnhancementTab, 'push');
            app.AddImageIEButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageIEButtonPushed, true);
            app.AddImageIEButton.Text = 'Add Image';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.HECitraInputAxes = uiaxes(app.HistogramEqualizationTab);
            title(app.HECitraInputAxes, 'Citra Input')
            app.HECitraInputAxes.XTick = [];
            app.HECitraInputAxes.YTick = [];
            app.HECitraInputAxes.ZTick = [];
            app.HistInputHEAxes = uiaxes(app.HistogramEqualizationTab);
            title(app.HistInputHEAxes, 'Histogram Citra')
            app.HistInputHEAxes.XTick = [];
            app.HistInputHEAxes.YTick = [];
            app.HistInputHEAxes.ZTick = [];
            app.HECitraOutputAxes = uiaxes(app.HistogramEqualizationTab);
            title(app.HECitraOutputAxes, 'Citra Hasil Perbaikan')
            app.HECitraOutputAxes.XTick = [];
            app.HECitraOutputAxes.YTick = [];
            app.HECitraOutputAxes.ZTick = [];
            app.HistOutputHEAxes = uiaxes(app.HistogramEqualizationTab);
            title(app.HistOutputHEAxes, 'Histogram Citra Hasil Perbaikan')
            app.HistOutputHEAxes.XTick = [];
            app.HistOutputHEAxes.YTick = [];
            app.HistOutputHEAxes.ZTick = [];
            app.AddImageDisplayHEButton = uibutton(app.HistogramEqualizationTab, 'push');
            app.AddImageDisplayHEButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageDisplayHEButtonPushed, true);
            app.AddImageDisplayHEButton.Text = 'Add Image';
            app.HistogramEqualizationLabel = uilabel(app.HistogramEqualizationTab);
            app.HistogramEqualizationLabel.Text = 'Histogram Equalization';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            app.HSCitraInputAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.HSCitraInputAxes, 'Citra Input')
            app.HSCitraInputAxes.XTick = [];
            app.HSCitraInputAxes.YTick = [];
            app.HSCitraInputAxes.ZTick = [];
            app.HSCitraRefAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.HSCitraRefAxes, 'Citra Referensi')
            app.HSCitraRefAxes.XTick = [];
            app.HSCitraRefAxes.YTick = [];
            app.HSCitraRefAxes.ZTick = [];
            app.HistInputHSAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.HistInputHSAxes, 'Histogram Citra Input')
            app.HistInputHSAxes.XTick = [];
            app.HistInputHSAxes.YTick = [];
            app.HistInputHSAxes.ZTick = [];
            app.HistRefHSAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.HistRefHSAxes, 'Histogram Citra Referensi')
            app.HistRefHSAxes.XTick = [];
            app.HistRefHSAxes.YTick = [];
            app.HistRefHSAxes.ZTick = [];
            app.HSCitraOutputAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.HSCitraOutputAxes, 'Citra Hasil Perbaikan')
            app.HSCitraOutputAxes.XTick = [];
            app.HSCitraOutputAxes.YTick = [];
            app.HSCitraOutputAxes.ZTick = [];
            app.HistOutputHSAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.HistOutputHSAxes, 'Histogram Citra Hasil Perbaikan')
            app.HistOutputHSAxes.XTick = [];
            app.HistOutputHSAxes.YTick = [];
            app.HistOutputHSAxes.ZTick = [];
            app.HistogramSpecificationLabel = uilabel(app.HistogramSpecificationTab);
            app.HistogramSpecificationLabel.Text = 'Histogram Specification';
            app.AddImageInputHSButton = uibutton(app.HistogramSpecificationTab, 'push');
            app.AddImageInputHSButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageInputHSButtonPushed, true);
            app.AddImageInputHSButton.Text = 'Add Citra Input';
            app.AddImageRefHSButton = uibutton(app.HistogramSpecificationTab, 'push');
            app.AddImageRefHSButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageRefHSButtonPushed, true);
            app.AddImageRefHSButton.Text = 'Add Citra Referensi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end